classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea         matlab.ui.control.TextArea
        BasicButton      matlab.ui.control.Button
        cosButton        matlab.ui.control.Button
        sinButton        matlab.ui.control.Button
        Button_sub       matlab.ui.control.Button
        Button_Pi        matlab.ui.control.Button
        Button_Mul       matlab.ui.control.Button
        Button_Div       matlab.ui.control.Button
        CButton          matlab.ui.control.Button
        Button_9         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_add       matlab.ui.control.Button
        Button_Eq        matlab.ui.control.Button
        Button_root      matlab.ui.control.Button
        Button_0         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        ButtonSq         matlab.ui.control.Button
        ButtonPer        matlab.ui.control.Button
        Button_1         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        CalculatorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonPer, Button_0, Button_1, Button_2, 
        % Button_3, Button_4, Button_5, Button_6, Button_7, Button_8, 
        % Button_9, Button_Div, Button_Mul, Button_Pi, Button_add, 
        % Button_sub, cosButton, sinButton
        function onClick(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text] ;
        end

        % Button pushed function: BasicButton
        function BasicButtonPushed(app, event)
            switch(app.BasicButton.Text)
                case "Basic"
                    app.BasicButton.Text ="Advance";
                    app.sinButton.Visible = 'off';
                    app.cosButton.Visible ='off';
                case "Advance"
                    app.BasicButton.Text ="Basic";
                    app.sinButton.Visible = 'on';
                    app.cosButton.Visible ='on'   ;
            end;
        end

        % Button pushed function: CButton
        function clearBtn(app, event)
            app.TextArea.Value='';

        end

        % Button pushed function: Button_Eq
        function EqBtn(app, event)
            try
                app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text ...
                    num2str(eval(app.TextArea.Value{1}))];
            catch
                app.TextArea.Value ="Error!";
            end;

        end

        % Button pushed function: Button_root
        function sqrt(app, event)
            app.TextArea.Value = num2str( sqrt(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: ButtonSq
        function ButtonSqPushed(app, event)
            app.TextArea.Value = num2str(str2double(app.TextArea.Value{1})^2);
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculatorLabel = uilabel(app.UIFigure);
            app.CalculatorLabel.Text = 'Calculator';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_7.Text = '7';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_4.Text = '4';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_1.Text = '1';
            app.ButtonPer = uibutton(app.UIFigure, 'push');
            app.ButtonPer.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.ButtonPer.Text = '.';
            app.ButtonSq = uibutton(app.UIFigure, 'push');
            app.ButtonSq.ButtonPushedFcn = createCallbackFcn(app, @ButtonSqPushed, true);
            app.ButtonSq.Text = 'x^2';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_8.Text = '8';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_5.Text = '5';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_2.Text = '2';
            app.Button_0 = uibutton(app.UIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_0.Text = '0';
            app.Button_root = uibutton(app.UIFigure, 'push');
            app.Button_root.ButtonPushedFcn = createCallbackFcn(app, @sqrt, true);
            app.Button_root.Text = '√x';
            app.Button_Eq = uibutton(app.UIFigure, 'push');
            app.Button_Eq.ButtonPushedFcn = createCallbackFcn(app, @EqBtn, true);
            app.Button_Eq.Text = '=';
            app.Button_add = uibutton(app.UIFigure, 'push');
            app.Button_add.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_add.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_3.Text = '3';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_6.Text = '6';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_9.Text = '9';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @clearBtn, true);
            app.CButton.Text = 'C';
            app.Button_Div = uibutton(app.UIFigure, 'push');
            app.Button_Div.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_Div.Text = '/';
            app.Button_Mul = uibutton(app.UIFigure, 'push');
            app.Button_Mul.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_Mul.Text = 'x';
            app.Button_Pi = uibutton(app.UIFigure, 'push');
            app.Button_Pi.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_Pi.Text = 'π';
            app.Button_sub = uibutton(app.UIFigure, 'push');
            app.Button_sub.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.Button_sub.Text = '-';
            app.sinButton = uibutton(app.UIFigure, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.sinButton.Text = 'sin';
            app.cosButton = uibutton(app.UIFigure, 'push');
            app.cosButton.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.cosButton.Text = 'cos';
            app.BasicButton = uibutton(app.UIFigure, 'push');
            app.BasicButton.ButtonPushedFcn = createCallbackFcn(app, @BasicButtonPushed, true);
            app.BasicButton.Text = 'Basic';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end